classdef Sim3D_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        ComputeButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize
            close all; clear all;
            addpath(genpath('../Declarations'),...
                    genpath('../Functions'),...
                    genpath('../Snippets'),...
                    genpath('../Simulator_3D'),...
                    genpath('../Simulator_1D'));
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % Rocket Definition
            Rocket = rocketReader('Rocket_Definition.txt');
            Environnement = environnementReader('Environnement_Definition.txt');
            
            %% ------------------------------------------------------------------------
            % 6DOF Rail Simulation
            %--------------------------------------------------------------------------
            
            % Initial Conditions
            X0 = [0,0]'; % positioned at 0 height and 0 velocity
            
            % time span 
            tspan = [0, 5];
            
            % options
            Option = odeset('Events', @(t,x) RailEvent(t,x,Environnement));
            
            % integration
            [T1,S1] = ode45(@(t,x) Dynamics_Rail_1DOF(t,x,Rocket,Environnement),tspan,X0, Option);
            
            %% ------------------------------------------------------------------------
            % 6DOF Flight Simulation
            %--------------------------------------------------------------------------
            
            % Rail vector
            C_rail = rotmat(Environnement.Rail_Angle, 2)*rotmat(-Environnement.Rail_Azimuth, 3);
            RV = C_rail*[0;0;1];
            
            % Initial Conditions
            X0 = RV*Environnement.Rail_Length; % spatial position of cm
            V0 = RV*S1(end,2); % Initial velocity of cm
            Q0 = rot2quat(C_rail); % Initial attitude
            W0 = [0;0;0]; % Initial angular rotation in rocket principle coordinates
            S0 = [X0; V0; Q0'; W0];
            
            % time span
            tspan = [T1(end), 100];
            
            % options
            Option = odeset('Events', @ApogeeEvent, 'RelTol', 1e-6, 'AbsTol', 1e-6);
            
            % integration
            [T2,S2, T2E, S2E, I2E] = ode45(@(t,s) Dynamics_6DOF(t,s,Rocket,Environnement),tspan,S0, Option);

            %% ------------------------------------------------------------------------
            % 6DOF Flight Simulation
            %--------------------------------------------------------------------------
            plot(app.UIAxes, T2, S2(:,3));
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Altitude vs. time')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sim3D_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end